classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        ImportImageMenu   matlab.ui.container.Menu
        NameLabel         matlab.ui.control.Label
        IdenButton        matlab.ui.control.Button
        TabGroup6         matlab.ui.container.TabGroup
        Tab_3             matlab.ui.container.Tab
        TabGroup5         matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        Button            matlab.ui.control.Button
        filterS           matlab.ui.control.NumericEditField
        sigma_sLabel      matlab.ui.control.Label
        filterR           matlab.ui.control.NumericEditField
        sigma_rLabel      matlab.ui.control.Label
        filterW           matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        Tab_2             matlab.ui.container.Tab
        LowButton         matlab.ui.control.Button
        HighButton        matlab.ui.control.Button
        Tab_4             matlab.ui.container.Tab
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button_2          matlab.ui.control.ToggleButton
        sobelButton       matlab.ui.control.ToggleButton
        prewittButton     matlab.ui.control.ToggleButton
        robertButton      matlab.ui.control.ToggleButton
        Tab_5             matlab.ui.container.Tab
        ButtonGroup_2     matlab.ui.container.ButtonGroup
        ObjLikeSpinner    matlab.ui.control.Spinner
        Label_7           matlab.ui.control.Label
        ObjKmeansSpinner  matlab.ui.control.Spinner
        KSpinnerLabel     matlab.ui.control.Label
        Button_7          matlab.ui.control.ToggleButton
        Button_5          matlab.ui.control.ToggleButton
        Button_4          matlab.ui.control.ToggleButton
        Tab_6             matlab.ui.container.Tab
        TabGroup7         matlab.ui.container.TabGroup
        LBPTab            matlab.ui.container.Tab
        LBPButton         matlab.ui.control.Button
        LBPR              matlab.ui.control.Spinner
        RSpinnerLabel     matlab.ui.control.Label
        LBPP              matlab.ui.control.Spinner
        PSpinnerLabel     matlab.ui.control.Label
        HOGTab            matlab.ui.container.Tab
        HOGButton         matlab.ui.control.Button
        HOGorientations   matlab.ui.control.Spinner
        Label_11          matlab.ui.control.Label
        HOGblock          matlab.ui.control.Spinner
        Label_10          matlab.ui.control.Label
        HOGcell           matlab.ui.control.Spinner
        Label_9           matlab.ui.control.Label
        Label3            matlab.ui.control.Label
        Label1            matlab.ui.control.Label
        exImage           matlab.ui.control.Image
        TabGroup          matlab.ui.container.TabGroup
        Tab1              matlab.ui.container.Tab
        TabGroup1         matlab.ui.container.TabGroup
        Tab11             matlab.ui.container.Tab
        linearButton      matlab.ui.control.Button
        b                 matlab.ui.control.NumericEditField
        bLabel            matlab.ui.control.Label
        a                 matlab.ui.control.NumericEditField
        aLabel            matlab.ui.control.Label
        linearF           matlab.ui.control.Image
        Tab12             matlab.ui.container.Tab
        logButton         matlab.ui.control.Button
        c                 matlab.ui.control.NumericEditField
        cLabel            matlab.ui.control.Label
        logF              matlab.ui.control.Image
        Tab13             matlab.ui.container.Tab
        index2            matlab.ui.control.NumericEditField
        cLabel_3          matlab.ui.control.Label
        index1            matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        indexF            matlab.ui.control.Image
        indexButton       matlab.ui.control.Button
        Tab2              matlab.ui.container.Tab
        TabGroup3         matlab.ui.container.TabGroup
        Tab21             matlab.ui.container.Tab
        YKnob             matlab.ui.control.Knob
        YKnobLabel        matlab.ui.control.Label
        XKnob             matlab.ui.control.Knob
        XKnobLabel        matlab.ui.control.Label
        Tab22             matlab.ui.container.Tab
        RotateKnob        matlab.ui.control.Knob
        Label_2           matlab.ui.control.Label
        Tab3              matlab.ui.container.Tab
        TabGroup4         matlab.ui.container.TabGroup
        Tab31             matlab.ui.container.Tab
        noiseStd          matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        noiseMean         matlab.ui.control.NumericEditField
        Label_4           matlab.ui.control.Label
        noiseButton1      matlab.ui.control.Button
        Tab32             matlab.ui.container.Tab
        noiseButton2      matlab.ui.control.Button
        noise2            matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        Label2            matlab.ui.control.Label
        showImage         matlab.ui.control.Image
        MatchButton       matlab.ui.control.Button
        BalanceButton     matlab.ui.control.Button
        UIImage           matlab.ui.control.Image
        UIAxesHistogram   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage   % 原始图像
        OriginalGrayImage % 原始灰度图像
        ProcessedImage  % 均衡化后的图像
        GrayImage       % 灰度图像
        tmpImage        % showImage所使用的图像
        ObjKmeansValue  % 聚类分割的聚类数
        ObjImage        % 目标提取图像
    end
    
    methods (Access = private)
        
        function updateHistogram(app, img)
            % 如果是彩色图像，将其转换为灰度图像
            if size(img, 3) == 3
                img = app.RGB2GRAY(img);
            end
            
            % 计算灰度直方图
            % 创建一个包含256个元素的零向量，用于存储每个灰度值的出现次数
            [rows, cols] = size(img);
            histogramData = zeros(1, 256);  % 0-255的灰度值
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j) + 1;  % +1 是因为灰度值从 0 开始
                    histogramData(grayValue) = histogramData(grayValue) + 1;
                end
            end
            
            % 清除之前的直方图
            cla(app.UIAxesHistogram);  % 清除 Axes 上之前的内容
            
            % 手动绘制直方图
            bar(app.UIAxesHistogram, 0:255, histogramData, 'FaceColor', 'b');
            title(app.UIAxesHistogram, '灰度直方图');
            xlabel(app.UIAxesHistogram, '灰度值');
            ylabel(app.UIAxesHistogram, '像素数量');
            axis(app.UIAxesHistogram, 'tight');  % 紧缩坐标轴
        end


        function balancedImg = customHistogramEqualization(app, img)
            if size(img, 3) == 3
                img = app.RGB2GRAY(img);
            end
            
            % 计算图像的直方图
            [rows, cols] = size(img);
            histogramData = zeros(1, 256);  % 0-255的灰度值
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j) + 1;  % +1 是因为灰度值从 0 开始
                    histogramData(grayValue) = histogramData(grayValue) + 1;
                end
            end
            
            % 计算累积分布函数（CDF）
            cdf = cumsum(histogramData) / (rows * cols);  % 归一化到 [0,1]
            
            % 创建新的均衡化图像
            balancedImg = uint8(zeros(rows, cols));  % 初始化均衡化后的图像
            for i = 1:rows
                for j = 1:cols
                    grayValue = img(i,j);  % 获取原图像的灰度值
                    % 映射到新的均衡化灰度值
                    balancedImg(i,j) = uint8(cdf(grayValue + 1) * 255);
                end
            end
        end
        
        function matchedImg = customHistogramMatching(app, sourceImg, targetImg)
            % 如果源图像和目标图像是彩色的，将其转换为灰度图像
            if size(sourceImg, 3) == 3
                sourceImg = app.RGB2GRAY(sourceImg);
            end
            if size(targetImg, 3) == 3
                targetImg = app.RGB2GRAY(targetImg);
            end
            
            % 获取图像的尺寸
            [rows, cols] = size(sourceImg);
            
            % 计算源图像的直方图
            sourceHist = zeros(1, 256);
            for i = 1:rows
                for j = 1:cols
                    grayValue = sourceImg(i,j) + 1;  % +1 因为灰度值从 0 开始
                    sourceHist(grayValue) = sourceHist(grayValue) + 1;
                end
            end
            
            % 计算目标图像的直方图
            targetHist = zeros(1, 256);
            for i = 1:rows
                for j = 1:cols
                    grayValue = targetImg(i,j) + 1;
                    targetHist(grayValue) = targetHist(grayValue) + 1;
                end
            end
            
            % 计算源图像的累积分布函数 (CDF)
            sourceCDF = cumsum(sourceHist) / (rows * cols);
            
            % 计算目标图像的累积分布函数 (CDF)
            targetCDF = cumsum(targetHist) / (rows * cols);
            
            % 对源图像的像素值进行映射
            matchedImg = uint8(zeros(rows, cols));
            for i = 1:rows
                for j = 1:cols
                    grayValue = sourceImg(i,j);
                    % 找到源图像像素的最小映射
                    [~, idx] = min(abs(sourceCDF(grayValue+1) - targetCDF));
                    matchedImg(i,j) = idx - 1;  % 映射后的像素值
                end
            end
            
        end
        
        function grayImg = RGB2GRAY(app,img)
            red = img(:,:,1);
            green= img(:,:,2);
            blue= img(:,:,3);
            % 按公式处理
            grayImg = red*0.299 + green*0.587 +blue* 0.114;
            grayImg = uint8(grayImg);
            
        end
        
        function startupFcn(app)
            app.XKnob.Limits = [0, 100];  % 设置 Knob 范围
            app.YKnob.Limits = [0, 100];  % 设置 Knob 范围
            
            % 设置刻度值的间隔
            app.XKnob.MajorTicks = 0:20:100;  % 设置主刻度
            app.YKnob.MajorTicks = 0:20:100;  % 设置主刻度
            % 确保 Knob 的初始值是正确的
            app.XKnob.Value = 20;  % 设置默认值
            app.YKnob.Value = 20;  % 设置默认值

        end
        
        function scaledImg = Amplification(app,img,kx,ky)
            % 直接处理彩色图像
            img = double(img);  % 将图像转换为double类型
            
            % 获取原始图像的尺寸
            [originalHeight, originalWidth, channels] = size(img);  % 获取彩色图像的尺寸
            
            % 计算新的图像尺寸
            newWidth = round(originalWidth * kx);
            newHeight = round(originalHeight * ky);
            
            % 创建新的坐标网格
            [X, Y] = meshgrid(1:newWidth, 1:newHeight);
            
            % 对应的原始图像坐标
            origX = (X - 1) / kx + 1;
            origY = (Y - 1) / ky + 1;
            
            % 确保坐标值在有效范围内
            origX = max(1, min(origX, originalWidth));
            origY = max(1, min(origY, originalHeight));
            
            % 为每个通道分别进行插值
            scaledImg = zeros(newHeight, newWidth, channels);
            
            for ch = 1:channels
                % 对每个颜色通道进行线性插值
                scaledImg(:, :, ch) = interp2(img(:, :, ch), origX, origY, 'linear', 0);
            end
            
            % 转换回 uint8 类型以便显示
            scaledImg = uint8(scaledImg);
        end
        
        function C = rotateImage(app, img, angle)
            img = double(img);  
            
            % 计算旋转角度的弧度值
            rad = deg2rad(angle);
            [rows, cols, channels] = size(img);  % 处理三通道彩色图像
            
            % 计算旋转后的图像大小
            Crows = floor(abs(rows * cos(rad)) + abs(cols * sin(rad))) + 1;
            Ccols = floor(abs(cols * cos(rad)) + abs(rows * sin(rad))) + 1;
            C = zeros(Crows, Ccols, channels);  % 创建一个新的空图像矩阵
            
            % 原始图像的中心
            origin_centerX = (cols + 1) / 2;
            origin_centerY = (rows + 1) / 2;
            
            % 新图像的中心
            new_centerX = (Ccols + 1) / 2;
            new_centerY = (Crows + 1) / 2;
            
            % 遍历新图像的每个像素点
            for i = 1:Crows
                for j = 1:Ccols
                    % 计算旋转前的坐标（考虑平移和旋转）
                    dy = i - new_centerY;
                    dx = j - new_centerX;
                    y_rot = dy * cos(rad) + dx * sin(rad) + origin_centerY;
                    x_rot = -dy * sin(rad) + dx * cos(rad) + origin_centerX;
                    
                    % 进行双线性插值
                    x1 = floor(x_rot);
                    y1 = floor(y_rot);
                    x2 = min(x1 + 1, cols);  % 防止越界
                    y2 = min(y1 + 1, rows);  % 防止越界
        
                    a = x_rot - x1;
                    b = y_rot - y1;
        
                    if x1 >= 1 && x1 <= cols && y1 >= 1 && y1 <= rows
                        for ch = 1:channels  % 对每个通道（RGB）进行插值
                            Q11 = img(y1, x1, ch);
                            Q12 = img(y1, x2, ch);
                            Q21 = img(y2, x1, ch);
                            Q22 = img(y2, x2, ch);
                            
                            % 双线性插值公式
                            C(i, j, ch) = (1 - a) * (1 - b) * Q11 + a * (1 - b) * Q21 + ...
                                           (1 - a) * b * Q12 + a * b * Q22;
                        end
                    else
                        % 如果在边界外，将像素置为黑色
                        C(i, j, :) = 0;
                    end
                end
            end
            
            % 转换回 uint8 类型以便显示
            C = uint8(C);
        end
        
        function edgeImage = robertsEdgeDetection(app,inputImage)
            if size(inputImage, 3) == 3
                inputImage = rgb2gray(inputImage);
            end
            [rows, cols] = size(inputImage);
            % 将图像转换为 double 类型进行计算
            inputImage = double(inputImage);
            
            % 定义 Roberts 卷积核
            Gx = [1 0; 0 -1];  % 水平方向
            Gy = [0 1; -1 0];  % 垂直方向
            
            % 进行卷积操作
            gradientX = zeros(rows, cols);
            gradientY = zeros(rows, cols);
        
            % 手动计算卷积
            for i = 1:rows-1
                for j = 1:cols-1
                    % 提取当前 2x2 的邻域
                    region = inputImage(i:i+1, j:j+1);
                    % 计算水平方向和垂直方向的梯度
                    gradientX(i, j) = sum(sum(region .* Gx));
                    gradientY(i, j) = sum(sum(region .* Gy));
                end
            end 
            
            % 计算梯度强度
            edgeImage = abs(gradientX) + abs(gradientY);  % 梯度强度的近似值
            
            % 归一化到 [0, 255]
            edgeImage = uint8(255 * mat2gray(edgeImage));
        end
        
        function edgeImage = prewittEdgeDetection(app,inputImage)
            % 将图像转换为灰度图像（如果是彩色图像）
            if size(inputImage, 3) == 3
                inputImage = rgb2gray(inputImage);
            end
            [rows, cols] = size(inputImage);
            % 将图像转换为 double 类型进行计算
            inputImage = double(inputImage);
        
            % 定义 Prewitt 卷积核
            Gx = [-1 0 1; -1 0 1; -1 0 1];  % 水平方向
            Gy = [-1 -1 -1; 0 0 0; 1 1 1];  % 垂直方向
        
            gradientX = zeros(rows, cols);
            gradientY = zeros(rows, cols);
        
            % 手动计算卷积
            for i = 2:rows-1
                for j = 2:cols-1
                    % 提取当前 3x3 的邻域
                    region = inputImage(i-1:i+1, j-1:j+1);
                    % 计算水平方向和垂直方向的梯度
                    gradientX(i, j) = sum(sum(region .* Gx));
                    gradientY(i, j) = sum(sum(region .* Gy));
                end
            end

            % 计算梯度幅值
            edgeImage = sqrt(gradientX.^2 + gradientY.^2);  % 梯度强度
        
            % 归一化到 [0, 255]
            edgeImage = uint8(255 * mat2gray(edgeImage));
        end
        
        function edgeImage = sobelEdgeDetection(app,inputImage)
            if size(inputImage, 3) == 3
                inputImage = rgb2gray(inputImage);
            end
            [rows, cols] = size(inputImage);
            % 将图像转换为 double 类型进行计算
            inputImage = double(inputImage);
        
            % 定义 Sobel 卷积核
            Gx = [-1 0 1; -2 0 2; -1 0 1];  % 水平方向
            Gy = [-1 -2 -1; 0 0 0; 1 2 1];  % 垂直方向
        
            gradientX = zeros(rows, cols);
            gradientY = zeros(rows, cols);
        
            % 手动计算卷积
            for i = 2:rows-1
                for j = 2:cols-1
                    % 提取当前 3x3 的邻域
                    region = inputImage(i-1:i+1, j-1:j+1);
                    % 计算水平方向和垂直方向的梯度
                    gradientX(i, j) = sum(sum(region .* Gx));
                    gradientY(i, j) = sum(sum(region .* Gy));
                end
            end
        
            % 计算梯度幅值
            edgeImage = sqrt(gradientX.^2 + gradientY.^2);  % 梯度强度
        
            % 归一化到 [0, 255]
            edgeImage = uint8(255 * mat2gray(edgeImage));
        end
        
        function edgeImage = laplacianEdgeDetection(app,inputImage)
            if size(inputImage, 3) == 3
                inputImage = rgb2gray(inputImage);
            end
            [rows, cols] = size(inputImage);
            inputImage = double(inputImage);
            laplacianKernel = [0 -1 0; -1 4 -1; 0 -1 0];  % 4-neighbor
            
             % 初始化梯度图像
            edgeImage = zeros(rows, cols);
        
            % 手动计算卷积
            for i = 2:rows-1
                for j = 2:cols-1
                    % 提取当前 3x3 的邻域
                    region = inputImage(i-1:i+1, j-1:j+1);
                    % 计算拉普拉斯算子
                    edgeImage(i, j) = sum(sum(region .* laplacianKernel));
                end
            end
        
            % 归一化到 [0, 255]
            edgeImage = uint8(255 * mat2gray(abs(edgeImage)));  % 取绝对值后归一化
        end
        
        function outputImageRGB = OstuObjectExtract(app)
            if size(app.OriginalImage, 3) == 3
                inputImage = app.RGB2GRAY(app.OriginalImage);
            end
            
            % 进行高斯滤波预处理，减少噪声干扰
            inputImage = imgaussfilt(inputImage, 5);  % 高斯滤波

            level = graythresh(inputImage);  % Otsu 阈值法，自动计算阈值
            binaryImage = inputImage > level * 255;  % 根据 Otsu 阈值进行二值化

            binaryImage = repmat(binaryImage, [1, 1, 3]);  % 将二值图像扩展为三通道
%             outputImageRGB = uint8(double(app.OriginalImage) .* ~binaryImage);
            outputImageRGB = uint8(double(app.OriginalImage) .* ~binaryImage);
            
        end
        
        function outputImageRGB = kmeansObjectExtract(app)
            if size(app.OriginalImage, 3) == 3
                inputImage = app.RGB2GRAY(app.OriginalImage);
            end
            [rows, cols] = size(inputImage);
            imageData = double(inputImage(:));  % 将图像转为列向量
            
            numClusters = app.ObjKmeansSpinner.Value;
            maxIter = 100;  % 最大迭代次数
            tolerance = 1e-4;  % 收敛容忍度
        
            initialCentroids = rand(numClusters, 1) * 255;  % 随机选择初始聚类中心
            
            previousCentroids = initialCentroids;
            for iter = 1:maxIter
                % 分配每个像素到最近的聚类中心
                distances = abs(imageData - previousCentroids');  % 计算每个像素与聚类中心的距离
                [~, clusterIdx] = min(distances, [], 2);  % 将每个像素分配给最近的聚类中心
        
                % 更新聚类中心
                newCentroids = zeros(numClusters, 1);
                for i = 1:numClusters
                    newCentroids(i) = mean(imageData(clusterIdx == i));  % 计算每个聚类的均值作为新的聚类中心
                end
                
                % 检查是否收敛
                if max(abs(newCentroids - previousCentroids)) < tolerance
                    break;
                end
                previousCentroids = newCentroids;
            end
            
            % 将聚类结果重构为图像
            segmentedImage = reshape(clusterIdx, [rows, cols]);  % 将聚类索引重构为原始图像尺寸
            
            % 将目标区域提取出来
            targetImage = segmentedImage == 1;  % 根据聚类索引选择目标区域
            
            % 创建输出彩色图像
            outputImageRGB = zeros(size(app.OriginalImage), 'uint8');  % 输出图像，初始化为黑色
            
            % 将目标区域对应的原图像颜色拷贝到输出图像
            outputImageRGB(:,:,1) = uint8(double(app.OriginalImage(:,:,1)) .* double(targetImage));  % Red channel
            outputImageRGB(:,:,2) = uint8(double(app.OriginalImage(:,:,2)) .* double(targetImage));  % Green channel
            outputImageRGB(:,:,3) = uint8(double(app.OriginalImage(:,:,3)) .* double(targetImage));  % Blue channel
            
        end
        
        function outputImageRGB = areaObjectExtract(app)
            if size(app.OriginalImage, 3) == 3
                inputImage = rgb2gray(app.OriginalImage);  % 转为灰度图
            end
            
            % Step 1: 显示图像并让用户点击选择种子点
            fig = figure;
            imshow(app.OriginalImage);  % 显示原始彩色图像
            title('点击图像选择种子点');
            
            % 使用ginput获取用户点击的种子点坐标（单击两次确定X和Y）
            [seedX, seedY] = ginput(1);  % 获取用户点击的坐标
            seedX = round(seedX);  % 保证是整数
            seedY = round(seedY);  % 保证是整数
            
            % 设置区域生长的阈值
            threshold = app.ObjLikeSpinner.Value;  % 相似度阈值，控制区域生长的精度
            close(fig);
            % 获取图像大小
            [rows, cols] = size(inputImage);
            
            % 初始化输出图像和访问记录
            outputImage = zeros(rows, cols);  % 初始化输出图像，0为背景，255为目标区域
            visited = false(rows, cols);  % 标记已访问的像素
            pixelList = [seedX, seedY];  % 存储待处理的像素列表，初始化时将种子点加入
            
            % Step 2: 区域生长算法
            while ~isempty(pixelList)
                % 取出一个待处理像素
                currentPixel = pixelList(1, :);
                pixelList(1, :) = [];  % 删除已处理的像素
                
                x = currentPixel(1);
                y = currentPixel(2);
                
                if visited(x, y)
                    continue;  % 如果该像素已经处理过，跳过
                end
                
                % 标记当前像素为已访问
                visited(x, y) = true;
                
                % 将当前像素加入目标区域
                outputImage(x, y) = 255;
                
                % Step 3: 检查邻域像素（4邻域或8邻域）
                for dx = -1:1
                    for dy = -1:1
                        % 忽略自己和超出边界的像素
                        if (dx == 0 && dy == 0) || x+dx < 1 || x+dx > rows || y+dy < 1 || y+dy > cols
                            continue;
                        end
                        
                        % 获取邻域像素的灰度值
                        neighborValue = inputImage(x+dx, y+dy);
                        
                        % 如果邻域像素与当前区域的灰度值相似，则加入待处理列表
                        if abs(double(neighborValue) - double(inputImage(seedX, seedY))) < threshold && ~visited(x+dx, y+dy)
                            pixelList = [pixelList; x+dx, y+dy];  % 将邻域像素加入待处理列表
                        end
                    end
                end
            end
            
            % Step 4: 根据分割的目标区域提取彩色图像
            % 将目标区域从原始图像中提取出来
            targetMask = uint8(outputImage) * 255;  % 目标区域的二值掩模
            
            % Step 5: 创建彩色输出图像并将目标区域保留颜色，背景区域变为黑色
            outputImageRGB = zeros([rows, cols, 3], 'uint8');  % 创建一个全黑的彩色图像
            
            % 将目标区域从原始彩色图像中提取出来
            for i = 1:3  % 对RGB三个通道进行操作
                outputImageRGB(:,:,i) = uint8(double(app.OriginalImage(:,:,i)) .* double(targetMask) / 255);  % 使用目标掩模提取目标区域
            end  
             % 获取原始图像减去目标区域的部分
            invertedTargetMask = ~targetMask;  % 反转二值掩模

            % 使用反转后的掩模提取背景区域
            outputImageRGB = uint8(double(app.OriginalImage) .* repmat(invertedTargetMask, [1, 1, 3]));
        end
        
        
        function lbpImage = extractLBPImage(app,inputImage, P, R)
            if size(inputImage, 3) == 3
                inputImage = app.RGB2GRAY(inputImage);  % 转为灰度图
            end
        
            [rows, cols] = size(inputImage);
            lbpImage = zeros(rows, cols);
        
            for i = 2:rows-1
                for j = 2:cols-1
                    % 获取当前像素的邻域（P个邻域点）
                    centerPixel = inputImage(i, j);
                    binaryPattern = zeros(1, P);
                    
                    for p = 1:P
                        % 计算每个邻域点的灰度值
                        angle = 2 * pi * (p - 1) / P;
                        dx = round(R * cos(angle));  % X轴偏移
                        dy = round(R * sin(angle));  % Y轴偏移
                        
                        % 获取邻域点的灰度值
                        neighborX = i + dx;
                        neighborY = j + dy;
                        
                        % 比较中心像素与邻域像素的灰度值
                        if inputImage(neighborX, neighborY) >= centerPixel
                            binaryPattern(p) = 1;
                        else
                            binaryPattern(p) = 0;
                        end
                    end
                    
                    % 将二进制模式转换为十进制并存储到 LBP 图像
                    lbpImage(i, j) = bi2de(binaryPattern, 'left-msb');
                end
            end
        end
        
        function [hog_features, hog_image] = extractHOGImage(app,inputImage, cellSize, blockSize, orientations)
             if size(inputImage, 3) == 3
                inputImage = app.RGB2GRAY(inputImage);  % 转为灰度图
             end
             
            % 将输入图像转换为双精度
            inputImage = double(inputImage);
            
            % 计算梯度（水平和垂直方向）
            [Gx, Gy] = imgradientxy(inputImage);
            
            % 计算每个像素的梯度幅度和方向
            [mag, ang] = imgradient(Gx, Gy);
            
            % 将图像分成小块（cell）
            [height, width] = size(inputImage);
            num_cellsX = floor(width / cellSize);
            num_cellsY = floor(height / cellSize);
            
            % 初始化HOG图像和特征向量
            hog_image = zeros(height, width);
            hog_features = [];
            
            % 计算每个cell的梯度直方图
            for i = 1:num_cellsY
                for j = 1:num_cellsX
                    % 获取cell区域
                    cell_x_start = (j-1) * cellSize + 1;
                    cell_x_end = j * cellSize;
                    cell_y_start = (i-1) * cellSize + 1;
                    cell_y_end = i * cellSize;
                    
                    % 获取cell内的梯度方向和幅度
                    cell_mag = mag(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    cell_ang = ang(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    
                    % 计算方向梯度直方图
                    hog_hist = app.compute_histogram(cell_mag, cell_ang, orientations);
                    
                    % 将每个cell的HOG特征存储到特征向量中
                    hog_features = [hog_features, hog_hist];
                    
                    % 可视化：构建HOG图像
                    for x = 1:cellSize
                        for y = 1:cellSize
                            angle = cell_ang(y, x);
                            magnitude = cell_mag(y, x);
                            % 将梯度可视化为箭头
                            hog_image(cell_y_start + y - 1, cell_x_start + x - 1) = magnitude;
                        end
                    end
                end
            end
            
            % 归一化HOG特征
            hog_features = app.normalize_hog(hog_features, blockSize, num_cellsX, num_cellsY);
        
        end
        
        % 计算方向梯度直方图
        function hist = compute_histogram(app,mag, ang, orientations)
            % 将角度转换到0-180度区间
            ang = mod(ang, 180);
            
            % 创建方向梯度直方图
            hist = zeros(1, orientations);
            
            % 计算每个像素的梯度方向对应的bin
            for i = 1:numel(ang)
                bin = floor(ang(i) / (180 / orientations)) + 1;
                hist(bin) = hist(bin) + mag(i);
            end
        end
        
        % 归一化HOG特征
        function hog_features = normalize_hog(app,hog_features, blockSize, num_cellsX, num_cellsY)
            % 归一化块（block）
            num_blocks_x = num_cellsX - blockSize + 1;
            num_blocks_y = num_cellsY - blockSize + 1;
            normalized_hog_features = [];
            
            for i = 1:num_blocks_y
                for j = 1:num_blocks_x
                    block_start_idx = (i-1) * num_cellsX + j;
                    block_end_idx = block_start_idx + blockSize * blockSize - 1;
                    block = hog_features(block_start_idx:block_end_idx);
                    
                    % L2 归一化
                    norm_factor = norm(block, 2);
                    if norm_factor > 0
                        block = block / norm_factor;
                    end
                    
                    normalized_hog_features = [normalized_hog_features, block];
                end
            end
            
            hog_features = normalized_hog_features;
        end
      
        
        function classesId = predict_with_onnx(app,onnx_model_name,  confT)
            onnxruntime = py.importlib.import_module('onnxruntime');
            session = onnxruntime.InferenceSession(onnx_model_name); 
        
            % 读取图像并进行预处理
            img = app.OriginalImage;
            img_data = app.preprocess_image(img);
        
            % 模型推理
            input_name = session.get_inputs{1}.name;
            % 确保 img_data 是正确的 Python 对象
            img_data_py = py.numpy.array(img_data);  % 将 img_data 转换为 numpy 数组
            
            % 调用模型进行推理，注意这里我们传递的是一个字典
            inputs_dict = py.dict(pyargs(input_name, img_data_py));  % 创建字典
        
            outputs = session.run(py.None, inputs_dict);  % 执行推理
        
            classesId = app.get_class_ids(outputs, confT);
        end
        
        function classes = load_classes(app,yaml_file)
            % 加载Python模块
            py.importlib.import_module('yaml');
           
            % 使用py.io.open读取yaml文件
            file_id = py.io.open(yaml_file, 'r');  % 打开文件对象
            yaml_data = py.yaml.safe_load(file_id);  % 使用safe_load而非load, 这个方法不需要指定Loader
            
            % 关闭文件对象
            file_id.close();
            classes = yaml_data{'names'}; 
        end
        function img_data = preprocess_image(app,img)
            % 对图像进行预处理以适应模型的输入要求
            % 假设输入大小为 (3, 640, 640)
            [img_height, img_width, ~] = size(img);
        
            % 将图像颜色空间从BGR转换为RGB (在MATLAB中读取的图像默认就是RGB)
            % 如果是BGR格式图像，可以用以下代码：
            % img = img(:,:, [3, 2, 1]); % 交换通道
            
            % 将图像调整为匹配输入形状 (640, 640, 3)
            img = imresize(img, [640, 640]); 
        
            img = single(img) / 255.0;  % 归一化
            
            % 转换为通道优先的格式 (C, H, W)
            img_data = permute(img, [3, 1, 2]);  % 将 (H, W, C) 转换为 (C, H, W)
            
            % 扩展维度为 (1, C, H, W) 以匹配模型的输入格式
            img_data = reshape(img_data, [1, size(img_data)]);
        end
        
        
        function targetId = get_class_ids(app,outputs, confT)
        
            output = outputs{1};  % output 是一个 numpy.ndarray（Python 类型）
            disp(class(output));
            output = py.numpy.squeeze(output);  % 移除单维度
            output_trans = py.numpy.transpose(output);
        
            output_list = output_trans.tolist();  % 使用 .tolist() 将其转换为 Python list
            
            % 获取输出的行数（即检测框的数量）
            num_boxes = double(py.len(output_list));  % 获取输出的行数，即检测框的数量
        
            class_ids = [];
            scores = [];
            
            % 遍历所有检测框
            for i = 1:num_boxes
                % 使用 MATLAB 的圆括号访问 Python list 中的第 i 行
                row = double(py.array.array('d', output_list{i}));  % 将每一行转换为 MATLAB 数组
        
                % 提取从第 5 列到末尾的得分部分
                scores_row  = row(5:end);  % 取出从第 5 列开始的得分
        
                
                [max_score, class_id] = max(scores_row);  % 找到最大得分及对应的类别 ID（MATLAB 中的索引从 1 开始）
        
                % 判断最大得分是否大于或等于置信度阈值
                if max_score >= confT
                    % 由于 MATLAB 中的索引从 1 开始，Python 的索引从 0 开始，所以需要减去 1
                    class_ids = [class_ids; class_id - 1];  % 保存类别 ID
                    scores = [scores; max_score];  % 保存得分
                end
            end
        
        %     disp(class_ids);
            targetId = mode(class_ids);
             if isempty(targetId)
                targetId = NaN;  % 返回 -1 表示没有有效的类别 ID
             end
        %      disp(targetId);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportImageMenu, Menu
        function ImportImageMenuSelected(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.gif', 'Image Files (*.jpg, *.png, *.jpeg, *.bmp, *.gif)'});
    
            if file ~= 0  % 如果用户选择了文件
                imgPath = fullfile(path, file);
                img = imread(imgPath);  % 读取图像
                
                % 显示图像，app.UIImage.ImageSource 需要是图像数组而不是路径
                app.UIImage.ImageSource = img;  % 将图像数据传递给 UIImage 组件
                app.OriginalImage = img;  % 保存原始图像
                
                % 如果图像是彩色的，转换为灰度图像
                if size(img, 3) == 3
                    img = app.RGB2GRAY(img);  % 转换为灰度图像
                end
                
                app.OriginalGrayImage = img;  % 保存原始图像
                
                % 使用自定义函数更新灰度直方图
                updateHistogram(app, img);  % 调用自定义函数显示灰度直方图
                
                app.GrayImage = cat(3, img, img, img);  % 直接保存灰度图像
            
                % 将灰度图像显示在 GrayImage 组件上
                app.showImage.ImageSource = app.GrayImage;  % 将灰度图像显示在 GrayImage 组件上
                app.tmpImage = app.GrayImage;
                app.Label2.Text = '灰度图像';
            else
                disp('No image selected');
            end
        end

        % Button pushed function: BalanceButton
        function BalanceButtonPushed(app, event)
            if isempty(app.OriginalGrayImage)
                % 如果没有导入图像，提示用户
                uialert(app.UIFigure, '请先导入图像！', '图像未加载');
                return;
            end
            
            % 对图像进行均衡化
            balancedImg = histeq(app.OriginalGrayImage);
            app.ProcessedImage = balancedImg;
            
            if size(balancedImg, 3) == 1  % 如果是灰度图像
                balancedImgRGB = cat(3, balancedImg, balancedImg, balancedImg);  % 将灰度图像转换为 RGB 图像
            else
                balancedImgRGB = balancedImg;  % 如果已经是彩色图像，则不需要转换
            end
            
            
            % 更新 UIImage 组件中的图像
            app.UIImage.ImageSource = balancedImgRGB;  % 显示均衡化后的图像（RGB 格式）
            
            % 使用自定义函数更新灰度直方图
            updateHistogram(app, balancedImg);  % 调用自定义函数显示均衡化后的灰度直方图
            app.Label1.Text = '直方图均衡化图像';
        end

        % Button pushed function: MatchButton
        function MatchButtonPushed(app, event)
            if isempty(app.OriginalGrayImage)
                % 如果没有导入图像，提示用户
                uialert(app.UIFigure, '请先导入源图像！', '图像未加载');
                return;
            end
            
            % 使用自定义的直方图均衡化函数生成目标图像
            targetImg = customHistogramEqualization(app, app.OriginalGrayImage);  % 均衡化后的图像
            
            % 使用自定义的直方图匹配函数
            matchedImg = customHistogramMatching(app, app.OriginalGrayImage, targetImg);
            
            % 更新处理后的图像
            app.ProcessedImage = matchedImg;
            
            % 将匹配后的灰度图像转换为 RGB 图像（如果需要显示在 UIImage 组件中）
            matchedImgRGB = cat(3, matchedImg, matchedImg, matchedImg);  % 转换为 RGB 图像
            
            % 更新 UIImage 组件中的图像
            app.UIImage.ImageSource = matchedImgRGB;  % 显示匹配后的图像（RGB 格式）
            
            % 使用自定义函数更新灰度直方图
            updateHistogram(app, matchedImg);  % 调用自定义函数显示匹配后的灰度直方图
            app.Label1.Text = '直方图匹配图像';
        end

        % Button pushed function: linearButton
        function linearButtonPushed(app, event)

            a = app.a.Value;
            b = app.b.Value;
            % 确保输入的值是有效的数字
            if isnan(a) || isnan(b)
                uialert(app.UIFigure, '请输入有效的增益和偏移值！', '输入无效');
                return;
            end
            
            % 获取原始灰度图像
            img = app.GrayImage;
            
            % 将图像从 uint8 转为 double 类型，以便进行线性变换
            img = double(img);
            
            % 线性增强
            enhancedImg = a * img + b;
            
            % 确保增强后的图像像素值在有效范围 [0, 255]
            enhancedImg = uint8(min(max(enhancedImg, 0), 255));
            
            % 更新灰度图像显示组件
            app.showImage.ImageSource = enhancedImg;  % 更新 UI 显示
            app.tmpImage = enhancedImg;
            app.Label2.Text = '线性增强';
        end

        % Button pushed function: logButton
        function logButtonPushed(app, event)
             % 获取用户输入的增益常数 c
            c = app.c.Value; 
            
            % 检查 c 是否有效
            if isnan(c)
                uialert(app.UIFigure, '请输入有效的增益常数 c！', '输入无效');
                return;
            end
            
            % 获取原始灰度图像
            img = app.GrayImage;  % 假设灰度图像已存在于 app.GrayImage 中
            
            % 确保图像是 double 类型，以便进行浮点运算
            img = double(img);
            
            % 对图像应用对数增强
            enhancedImg = c * log(1 + img);
            
            % 限制图像像素值在 0 到 255 之间，并转换回 uint8 类型
            enhancedImg = uint8(min(max(enhancedImg, 0), 255));
            
            app.showImage.ImageSource = enhancedImg;  % 刷新图像组件显示
            app.tmpImage = enhancedImg;
            app.Label2.Text = '对数增强';
        end

        % Button pushed function: indexButton
        function indexButtonPushed(app, event)
            
            alpha = app.index1.Value; 
            c = app.index2.Value; 
            
            % 检查 c 是否有效
            if isnan(c)||isnan(alpha)
                uialert(app.UIFigure, '请输入有效的增益常数 c！', '输入无效');
                return;
            end
            
            % 获取原始灰度图像
            img = app.GrayImage;  
            
            % 确保图像是 double 类型，以便进行浮点运算
            img = double(img);
            
           enhancedImg = c * (exp(alpha * img) - 1);
    
            % 限制图像像素值在 0 到 255 之间，并转换回 uint8 类`型
            enhancedImg = uint8(min(max(enhancedImg, 0), 255));
            
            app.showImage.ImageSource = enhancedImg;  % 刷新图像组件显示
            app.tmpImage = enhancedImg;
            app.Label2.Text = '指数增强';
        end

        % Value changed function: XKnob
        function XKnobValueChanged(app, event)
            scale_x = app.XKnob.Value / 20;  % X 方向缩放倍数
            scale_y = app.YKnob.Value / 20;  % Y 方向缩放倍数
        
            % 确保缩放倍数是正数
            if scale_x <= 0 || scale_y <= 0
                uialert(app.UIFigure, '缩放倍数必须为正！', '无效输入');
                return;
            end
        
            % 获取原始图像
            img = app.OriginalImage;  

            resized_img = app.Amplification(img,scale_x, scale_y);
            % 更新 grayImage 的显示
            app.showImage.ImageSource = resized_img;  % 刷新显示
            app.tmpImage = resized_img;
            app.Label2.Text = sprintf('%.2f x %.2f', scale_x, scale_y);
            
        end

        % Value changed function: YKnob
        function YKnobValueChanged(app, event)
            scale_x = app.XKnob.Value / 20;  % X 方向缩放倍数
            scale_y = app.YKnob.Value / 20;  % Y 方向缩放倍数
        
            % 确保缩放倍数是正数
            if scale_x <= 0 || scale_y <= 0
                uialert(app.UIFigure, '缩放倍数必须为正！', '无效输入');
                return;
            end
        
            % 获取原始图像
            img = app.OriginalImage;  

            resized_img = app.Amplification(img,scale_x, scale_y);
            % 更新 grayImage 的显示
            app.showImage.ImageSource = resized_img;  % 刷新显示
            app.tmpImage = resized_img;
            app.Label2.Text = sprintf('%.2f x %.2f', scale_x, scale_y);
            
        end

        % Value changed function: RotateKnob
        function RotateKnobValueChanged(app, event)
            % 获取当前的旋转角度
            angle = app.RotateKnob.Value;
            
            rotatedImg = app.rotateImage(app.OriginalImage,-angle);
            
            app.showImage.ImageSource = rotatedImg;
            app.tmpImage = rotatedImg;
            % 更新 Label 显示旋转角度
            app.Label2.Text = sprintf('旋转:%.1f°', angle);  % 显示当前旋转角度
            
        end

        % Button pushed function: noiseButton1
        function noiseButton1Pushed(app, event)
            img = double(app.OriginalImage);
            noiseMean = app.noiseMean.Value;
            noiseStd = app.noiseStd.Value;
            % 生成高斯噪声
            noise = noiseMean + noiseStd * randn(size(img));  % 高斯噪声（均值mean，标准差stddev）
        
            % 将噪声加到图像上
            noisyImg = img + noise;
        
            % 保证图像值在合法范围内 [0, 255]
            noisyImg = uint8(max(0, min(255, noisyImg)));  % 对结果进行裁剪
            app.showImage.ImageSource =noisyImg;
            app.tmpImage = noisyImg;
            app.Label2.Text = '高斯噪声图片';
        end

        % Button pushed function: noiseButton2
        function noiseButton2Pushed(app, event)
            % 将图像转换为double类型
            img = double(app.OriginalImage);
            density = app.noise2.Value;
            % 计算噪声的像素数
            numPixels = numel(img);
            numSalt = round(density * numPixels / 2);  % 盐噪声的像素数
            numPepper = round(density * numPixels / 2);  % 胡椒噪声的像素数
        
            % 生成盐噪声的位置
            saltIdx = randperm(numPixels, numSalt);
            pepperIdx = randperm(numPixels, numPepper);
        
            % 将盐噪声（255）加到图像上
            img(saltIdx) = 255;
            
            % 将胡椒噪声（0）加到图像上
            img(pepperIdx) = 0;
        
            % 将噪声图像转换回uint8类型
            noisyImg = uint8(img);
            app.showImage.ImageSource =noisyImg;
            app.tmpImage = noisyImg;
            app.Label2.Text = '椒盐噪声图片';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            img = double(app.tmpImage);
            w = app.filterW.Value;
            sigma_s = app.filterS.Value;
            sigma_r = app.filterR.Value;
            [rows, cols, channels] = size(img);  
            % 输出图像初始化
            filteredImg = zeros(rows, cols);
            
            % 对每个像素进行双边滤波
            half_w = floor(w/2);  % 滤波窗口的半径
            for c = 1:channels  % 遍历每个颜色通道
                for i = 1:rows
                    for j = 1:cols
                        % 初始化加权值和归一化因子
                        weighted_sum = 0;
                        normalization_factor = 0;
                        
                        % 遍历窗口区域
                        for m = -half_w:half_w
                            for n = -half_w:half_w
                                % 计算邻域像素的坐标
                                ni = i + m;
                                nj = j + n;
                                
                                % 确保邻域坐标在图像范围内
                                if ni >= 1 && ni <= rows && nj >= 1 && nj <= cols
                                    % 计算空间距离和灰度值差异
                                    spatial_dist = m^2 + n^2;
                                    intensity_dist = (img(i,j,c) - img(ni,nj,c))^2;
                                    
                                    % 计算空间权重
                                    spatial_weight = exp(-spatial_dist / (2 * sigma_s^2));
                                    
                                    % 计算灰度权重
                                    intensity_weight = exp(-intensity_dist / (2 * sigma_r^2));
                                    
                                    % 总权重是空间权重和灰度权重的乘积
                                    weight = spatial_weight * intensity_weight;
                                    
                                    % 加权值和归一化因子更新
                                    weighted_sum = weighted_sum + weight * img(ni, nj, c);
                                    normalization_factor = normalization_factor + weight;
                                end
                            end
                        end
                        
                        % 计算滤波后图像的像素值
                        filteredImg(i, j, c) = weighted_sum / normalization_factor;
                    end
                end
            end
            
            % 将输出图像转换为uint8格式
            filteredImg = uint8(filteredImg); 
            app.exImage.ImageSource = filteredImg;
         

        end

        % Button pushed function: LowButton
        function LowButtonPushed(app, event)
            if size(app.tmpImage, 3) == 3
                inputImage = rgb2gray(app.tmpImage);
            else
                inputImage = app.tmpImage;
            end
            
            D0 = 30;  % 低通滤波器的截止频率
            % 获取图像尺寸
            [M, N] = size(inputImage);
        
            % 对图像进行傅里叶变换
            F = fft2(double(inputImage));
            Fshift = fftshift(F);  % 将频谱移到中心
        
            % 生成理想低通滤波器
            % 创建与输入图像尺寸匹配的频率坐标
            [U, V] = meshgrid(-floor(N/2):floor(N/2)-1, -floor(M/2):floor(M/2)-1);
        
            % 计算频率距离
            D = sqrt(U.^2 + V.^2);
        
            % 创建理想低通滤波器，尺寸与 Fshift 相同
            H = double(D <= D0);  % 低通滤波器
        
            % 确保滤波器的尺寸与频谱尺寸一致
            [H_M, H_N] = size(H);
            if H_M ~= M || H_N ~= N
                H = imresize(H, [M, N]);  % 调整滤波器尺寸
            end
        
            % 应用低通滤波器
            Fshift_filtered = Fshift .* H;
        
            % 逆傅里叶变换得到图像
            F_filtered = ifftshift(Fshift_filtered);  % 频谱移回原始位置
            outputImage = real(ifft2(F_filtered));
        
            % 将输出图像限制在合法范围内并转换为 uint8
            outputImage = uint8(max(0, min(255, outputImage)));
            outputImageRGB = cat(3,outputImage,outputImage,outputImage);
            % 显示结果
            app.exImage.ImageSource = outputImageRGB;  
            app.Label3.Text = '低通滤波图像';
        end

        % Button pushed function: HighButton
        function HighButtonPushed(app, event)
            if size(app.tmpImage, 3) == 3
                inputImage = rgb2gray(app.tmpImage);
            else
                inputImage = app.tmpImage;
            end
            
            D0 = 10;  % 高通滤波器的截止频率
            % 获取图像尺寸
            [M, N] = size(inputImage);
        
            % 对图像进行傅里叶变换
            F = fft2(double(inputImage));
            Fshift = fftshift(F);  % 将频谱移到中心
        
            % 生成理想高通滤波器
            % 创建与输入图像尺寸匹配的频率坐标
            [U, V] = meshgrid(-floor(N/2):floor(N/2)-1, -floor(M/2):floor(M/2)-1);
        
            % 计算频率距离
            D = sqrt(U.^2 + V.^2);
        
            % 创建理想高通滤波器，尺寸与 Fshift 相同
            H = double(D > D0);  % 高通滤波器
        
            % 确保滤波器的尺寸与频谱尺寸一致
            [H_M, H_N] = size(H);
            if H_M ~= M || H_N ~= N
                H = imresize(H, [M, N]);  % 调整滤波器尺寸
            end
        
            % 应用高通滤波器
            Fshift_filtered = Fshift .* H;
        
            % 逆傅里叶变换得到图像
            F_filtered = ifftshift(Fshift_filtered);  % 频谱移回原始位置
            outputImage = real(ifft2(F_filtered));
        
            % 将输出图像限制在合法范围内并转换为 uint8
            outputImage = uint8(max(0, min(255, outputImage)));
            outputImageRGB = cat(3,outputImage,outputImage,outputImage);
            % 显示结果
            app.exImage.ImageSource = outputImageRGB;  
            app.Label3.Text = '高通滤波图像';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            selectedTag = selectedButton.Tag;
            switch selectedTag
                case 'robert'
                    edgeImg = app.robertsEdgeDetection(app.OriginalImage);
                    app.Label3.Text = 'robert算子';
                case 'prewitt'
                    edgeImg = app.prewittEdgeDetection(app.OriginalImage);
                    app.Label3.Text = 'prewitt算子';
                case 'sobel'
                    edgeImg = app.sobelEdgeDetection(app.OriginalImage);
                    app.Label3.Text = 'sobel算子';
                case 'lapu'
                    edgeImg = app.laplacianEdgeDetection(app.OriginalImage);
                    app.Label3.Text = '拉普拉斯算子';
                otherwise
                    edgeImg = app.RGB2GRAY(app.OriginalImage);
            end
            edgeImgRGB = cat(3,edgeImg,edgeImg,edgeImg);
            app.exImage.ImageSource = edgeImgRGB;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            selectedTag = selectedButton.Tag;
            outputImg = app.OriginalImage;
            switch selectedTag
                case '阈值分割'
                    outputImg = app.OstuObjectExtract();
                case '聚类分割'
                    outputImg = app.kmeansObjectExtract();
                case '区域分割'
                    outputImg = app.areaObjectExtract();
            end
            app.Label3.Text = '目标提取结果';
            app.ObjImage = outputImg;
            app.exImage.ImageSource = outputImg;
        end

        % Button pushed function: LBPButton
        function LBPButtonPushed(app, event)
            inputImage1 = app.OriginalImage;
            inputImage2 = app.ObjImage;
            P = app.LBPP.Value;
            R = app.LBPR.Value;
            lbpImage1 = app.extractLBPImage(inputImage1, P, R);
            lbpImage2 = app.extractLBPImage(inputImage2, P, R);
            lbpImageRGB1 = cat(3,lbpImage1,lbpImage1,lbpImage1);
            lbpImageRGB2 = cat(3,lbpImage2,lbpImage2,lbpImage2);
            app.exImage.ImageSource = lbpImageRGB1;
            app.Label3.Text = '原始图像特征提取';
            app.showImage.ImageSource = lbpImageRGB2;
            app.Label2.Text = '提取目标特征提取';
        end

        % Button pushed function: HOGButton
        function HOGButtonPushed(app, event)
            inputImage1 = app.OriginalImage;
            inputImage2 = app.ObjImage;
            cellSize = app.HOGcell.Value;
            blockSize = app.HOGblock.Value;
            orientations = app.HOGorientations.Value;
            [hogFeatures1,hogImage1] = app.extractHOGImage(inputImage1,cellSize, blockSize, orientations);
            hogImage1 = mat2gray((hogImage1));
            hogImage1RGB = cat(3,hogImage1,hogImage1,hogImage1);
            [hogFeatures2,hogImage2] = app.extractHOGImage(inputImage2,cellSize, blockSize, orientations);
            hogImage2 = mat2gray((hogImage2));
            hogImage2RGB = cat(3,hogImage2,hogImage2,hogImage2);
            app.exImage.ImageSource = hogImage1RGB;
            app.Label3.Text = '原始图像特征提取';
            app.showImage.ImageSource = hogImage2RGB;
            app.Label2.Text = '提取目标特征提取';
        end

        % Button pushed function: IdenButton
        function IdenButtonPushed(app, event)
            onnx_model_name = 'Birds.onnx';
            confT = 0.3;  % 置信度阈值
            classes = app.load_classes('./birds.yaml'); 
            classesId = app.predict_with_onnx(onnx_model_name, confT);
            if isnan(classesId)
                app.NameLabel.Text = "无法识别鸟类";
            else
                app.NameLabel.Text = char(classes{classesId});
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.Menu.Text = 'Menu';
            app.ImportImageMenu = uimenu(app.Menu);
            app.ImportImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportImageMenuSelected, true);
            app.ImportImageMenu.Text = 'Import Image';
            app.UIAxesHistogram = uiaxes(app.UIFigure);
            title(app.UIAxesHistogram, '灰度直方图')
            xlabel(app.UIAxesHistogram, '灰度值')
            ylabel(app.UIAxesHistogram, '像素数量')
            zlabel(app.UIAxesHistogram, 'Z')
            app.UIImage = uiimage(app.UIFigure);
            app.UIImage.ImageSource = 'flower.png';
            app.BalanceButton = uibutton(app.UIFigure, 'push');
            app.BalanceButton.ButtonPushedFcn = createCallbackFcn(app, @BalanceButtonPushed, true);
            app.BalanceButton.Text = '均衡化';
            app.MatchButton = uibutton(app.UIFigure, 'push');
            app.MatchButton.ButtonPushedFcn = createCallbackFcn(app, @MatchButtonPushed, true);
            app.MatchButton.Text = '匹配';
            app.showImage = uiimage(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = {'灰度图像'; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = '对比度增强';
            app.TabGroup1 = uitabgroup(app.Tab1);
            app.Tab11 = uitab(app.TabGroup1);
            app.Tab11.Title = '线性增强';
            app.linearF = uiimage(app.Tab11);
            app.linearF.ImageSource = '线性增强.png';
            app.aLabel = uilabel(app.Tab11);
            app.aLabel.Text = 'a =';
            app.a = uieditfield(app.Tab11, 'numeric');
            app.a.Value = 1;
            app.bLabel = uilabel(app.Tab11);
            app.bLabel.Text = 'b =';
            app.b = uieditfield(app.Tab11, 'numeric');
            app.linearButton = uibutton(app.Tab11, 'push');
            app.linearButton.ButtonPushedFcn = createCallbackFcn(app, @linearButtonPushed, true);
            app.linearButton.Text = '确认';
            app.Tab12 = uitab(app.TabGroup1);
            app.Tab12.Title = '对数增强';
            app.logF = uiimage(app.Tab12);
            app.logF.ImageSource = '对数增强.png';
            app.cLabel = uilabel(app.Tab12);
            app.cLabel.Text = 'c =';
            app.c = uieditfield(app.Tab12, 'numeric');
            app.c.Value = 1;
            app.logButton = uibutton(app.Tab12, 'push');
            app.logButton.ButtonPushedFcn = createCallbackFcn(app, @logButtonPushed, true);
            app.logButton.Text = '确认';
            app.Tab13 = uitab(app.TabGroup1);
            app.Tab13.Title = '指数增强';
            app.indexButton = uibutton(app.Tab13, 'push');
            app.indexButton.ButtonPushedFcn = createCallbackFcn(app, @indexButtonPushed, true);
            app.indexButton.Text = '确认';
            app.indexF = uiimage(app.Tab13);
            app.indexF.ImageSource = '指数增强.png';
            app.Label = uilabel(app.Tab13);
            app.Label.Text = 'α =';
            app.index1 = uieditfield(app.Tab13, 'numeric');
            app.index1.Value = 1;
            app.cLabel_3 = uilabel(app.Tab13);
            app.cLabel_3.Text = 'c =';
            app.index2 = uieditfield(app.Tab13, 'numeric');
            app.index2.Value = 1;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = '图像变换';
            app.TabGroup3 = uitabgroup(app.Tab2);
            app.Tab21 = uitab(app.TabGroup3);
            app.Tab21.Title = '缩放变换';
            app.XKnobLabel = uilabel(app.Tab21);
            app.XKnobLabel.Text = 'X放缩';
            app.XKnob = uiknob(app.Tab21, 'continuous');
            app.XKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.XKnob.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3', '3.5', '4', '4.5', '5'};
            app.XKnob.ValueChangedFcn = createCallbackFcn(app, @XKnobValueChanged, true);
            app.XKnob.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.XKnob.Value = 20;
            app.YKnobLabel = uilabel(app.Tab21);
            app.YKnobLabel.Text = 'Y放缩';
            app.YKnob = uiknob(app.Tab21, 'continuous');
            app.YKnob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.YKnob.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3', '3.5', '4', '4.5', '5'};
            app.YKnob.ValueChangedFcn = createCallbackFcn(app, @YKnobValueChanged, true);
            app.YKnob.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.YKnob.Value = 20;
            app.Tab22 = uitab(app.TabGroup3);
            app.Tab22.Title = '旋转变换';
            app.Label_2 = uilabel(app.Tab22);
            app.Label_2.Text = '旋转';
            app.RotateKnob = uiknob(app.Tab22, 'continuous');
            app.RotateKnob.Limits = [-180 180];
            app.RotateKnob.MajorTicks = [-180 -144 -108 -72 -36 0 36 72 108 144 180];
            app.RotateKnob.MajorTickLabels = {'-180', '-144', '-108', '-72', '-36', '0', '36', '72', '108', '144', '180'};
            app.RotateKnob.ValueChangedFcn = createCallbackFcn(app, @RotateKnobValueChanged, true);
            app.RotateKnob.MinorTicks = [-180 -168 -156 -144 -132 -120 -108 -96 -84 -72 -60 -48 -36 -24 -12 0 12 24 36 48 60 72 84 96 108 120 132 144 156 168 180];
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = '图像加噪';
            app.TabGroup4 = uitabgroup(app.Tab3);
            app.Tab31 = uitab(app.TabGroup4);
            app.Tab31.Title = '高斯噪声';
            app.noiseButton1 = uibutton(app.Tab31, 'push');
            app.noiseButton1.ButtonPushedFcn = createCallbackFcn(app, @noiseButton1Pushed, true);
            app.noiseButton1.Text = '加入噪声';
            app.Label_4 = uilabel(app.Tab31);
            app.Label_4.Text = 'μ =';
            app.noiseMean = uieditfield(app.Tab31, 'numeric');
            app.Label_5 = uilabel(app.Tab31);
            app.Label_5.Text = 'σ =';
            app.noiseStd = uieditfield(app.Tab31, 'numeric');
            app.Tab32 = uitab(app.TabGroup4);
            app.Tab32.Title = '椒盐噪声';
            app.Label_3 = uilabel(app.Tab32);
            app.Label_3.Text = '噪声密度 =';
            app.noise2 = uieditfield(app.Tab32, 'numeric');
            app.noiseButton2 = uibutton(app.Tab32, 'push');
            app.noiseButton2.ButtonPushedFcn = createCallbackFcn(app, @noiseButton2Pushed, true);
            app.noiseButton2.Text = '加入噪声';
            app.exImage = uiimage(app.UIFigure);
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = '原始图像';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = {'滤波图像'; ''};
            app.TabGroup6 = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup6);
            app.Tab_3.Title = '图像滤波';
            app.TabGroup5 = uitabgroup(app.Tab_3);
            app.Tab = uitab(app.TabGroup5);
            app.Tab.Title = '空域滤波';
            app.Label_6 = uilabel(app.Tab);
            app.Label_6.Text = 'ω =';
            app.filterW = uieditfield(app.Tab, 'numeric');
            app.filterW.Value = 1;
            app.sigma_rLabel = uilabel(app.Tab);
            app.sigma_rLabel.Interpreter = 'latex';
            app.sigma_rLabel.Text = '$\sigma_r$ =';
            app.filterR = uieditfield(app.Tab, 'numeric');
            app.filterR.Value = 0.1;
            app.sigma_sLabel = uilabel(app.Tab);
            app.sigma_sLabel.Interpreter = 'latex';
            app.sigma_sLabel.Text = {'$\sigma_s$ ='; ''};
            app.filterS = uieditfield(app.Tab, 'numeric');
            app.filterS.Value = 10;
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '双边滤波';
            app.Tab_2 = uitab(app.TabGroup5);
            app.Tab_2.Title = '频域滤波';
            app.HighButton = uibutton(app.Tab_2, 'push');
            app.HighButton.ButtonPushedFcn = createCallbackFcn(app, @HighButtonPushed, true);
            app.HighButton.Text = '高通滤波';
            app.LowButton = uibutton(app.Tab_2, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @LowButtonPushed, true);
            app.LowButton.Text = '低通滤波';
            app.Tab_4 = uitab(app.TabGroup6);
            app.Tab_4.Title = '边缘提取';
            app.ButtonGroup = uibuttongroup(app.Tab_4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '算子选取';
            app.ButtonGroup.Tag = 'robert';
            app.robertButton = uitogglebutton(app.ButtonGroup);
            app.robertButton.Tag = 'robert';
            app.robertButton.Text = 'robert算子';
            app.robertButton.Value = true;
            app.prewittButton = uitogglebutton(app.ButtonGroup);
            app.prewittButton.Tag = 'prewitt';
            app.prewittButton.Text = 'prewitt算子';
            app.sobelButton = uitogglebutton(app.ButtonGroup);
            app.sobelButton.Tag = 'sobel';
            app.sobelButton.Text = 'sobel算子';
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Tag = 'lapu';
            app.Button_2.Text = '拉普拉斯算子';
            app.Tab_5 = uitab(app.TabGroup6);
            app.Tab_5.Title = '目标提取';
            app.ButtonGroup_2 = uibuttongroup(app.Tab_5);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '目标提取方法';
            app.Button_4 = uitogglebutton(app.ButtonGroup_2);
            app.Button_4.Tag = '阈值分割';
            app.Button_4.Text = '阈值分割';
            app.Button_4.Value = true;
            app.Button_5 = uitogglebutton(app.ButtonGroup_2);
            app.Button_5.Tag = '聚类分割';
            app.Button_5.Text = '聚类分割';
            app.Button_7 = uitogglebutton(app.ButtonGroup_2);
            app.Button_7.Tag = '区域分割';
            app.Button_7.Text = '区域分割';
            app.KSpinnerLabel = uilabel(app.ButtonGroup_2);
            app.KSpinnerLabel.Text = 'K';
            app.ObjKmeansSpinner = uispinner(app.ButtonGroup_2);
            app.ObjKmeansSpinner.Limits = [1 Inf];
            app.ObjKmeansSpinner.Value = 1;
            app.Label_7 = uilabel(app.ButtonGroup_2);
            app.Label_7.Text = '相似度';
            app.ObjLikeSpinner = uispinner(app.ButtonGroup_2);
            app.ObjLikeSpinner.Limits = [1 100];
            app.ObjLikeSpinner.Value = 10;
            app.Tab_6 = uitab(app.TabGroup6);
            app.Tab_6.Title = '特征提取';
            app.TabGroup7 = uitabgroup(app.Tab_6);
            app.LBPTab = uitab(app.TabGroup7);
            app.LBPTab.Title = 'LBP';
            app.PSpinnerLabel = uilabel(app.LBPTab);
            app.PSpinnerLabel.Text = '领域点数P';
            app.LBPP = uispinner(app.LBPTab);
            app.LBPP.Limits = [8 Inf];
            app.LBPP.Value = 8;
            app.RSpinnerLabel = uilabel(app.LBPTab);
            app.RSpinnerLabel.Text = '半径R';
            app.LBPR = uispinner(app.LBPTab);
            app.LBPR.Limits = [1 Inf];
            app.LBPR.Value = 1;
            app.LBPButton = uibutton(app.LBPTab, 'push');
            app.LBPButton.ButtonPushedFcn = createCallbackFcn(app, @LBPButtonPushed, true);
            app.LBPButton.Text = '特征提取';
            app.HOGTab = uitab(app.TabGroup7);
            app.HOGTab.Title = 'HOG';
            app.Label_9 = uilabel(app.HOGTab);
            app.Label_9.Text = '单元格大小';
            app.HOGcell = uispinner(app.HOGTab);
            app.HOGcell.Limits = [8 Inf];
            app.HOGcell.Value = 8;
            app.Label_10 = uilabel(app.HOGTab);
            app.Label_10.Text = '块大小';
            app.HOGblock = uispinner(app.HOGTab);
            app.HOGblock.Limits = [2 Inf];
            app.HOGblock.Value = 2;
            app.Label_11 = uilabel(app.HOGTab);
            app.Label_11.Text = '梯度方向数';
            app.HOGorientations = uispinner(app.HOGTab);
            app.HOGorientations.Value = 9;
            app.HOGButton = uibutton(app.HOGTab, 'push');
            app.HOGButton.ButtonPushedFcn = createCallbackFcn(app, @HOGButtonPushed, true);
            app.HOGButton.Text = '特征提取';
            app.IdenButton = uibutton(app.UIFigure, 'push');
            app.IdenButton.ButtonPushedFcn = createCallbackFcn(app, @IdenButtonPushed, true);
            app.IdenButton.Text = '鸟类识别';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = '鸟类名称';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end